classdef ThacherIllusionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        VideoControlPanel           matlab.ui.container.Panel
        VideoSourceDropDownLabel    matlab.ui.control.Label
        VideoSourceDropDown         matlab.ui.control.DropDown
        PlayVideoButton             matlab.ui.control.StateButton
        ReDetectObjectsButton       matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        IllusionStatusControlPanel  matlab.ui.container.Panel
        IllusionSwitchLabel         matlab.ui.control.Label
        IllusionSwitch              matlab.ui.control.RockerSwitch
        VerticalFlipSwitchLabel     matlab.ui.control.Label
        VerticalFlipSwitch          matlab.ui.control.RockerSwitch
    end

    
    properties (Access = private)
        cam; % カメラオブジェクト
        UpdateTimer;%タイマオブジェクト
        runLoop;
        XExpand;
        YExpand;
        
        FaceDetector;
        
        %口の位置推定関係プロパティ
        MouthDetector;
        MouthNumPts;
        MouthTracker;
        MouthbboxPoints;
        MouthOldPoints;

        
        %目の位置推定関係プロパティ
        EyesDetector;
        EyesTracker;
        EyesNumPts;
        EyesbboxPoints;
        EyesOldPoints;

        EnableIllusion;% Description
        VerticalFlip;% Description
    end
    
    methods (Access = private)
        
        function mytimer_func(app, obj, event)
            VideoFrame = snapshot(app.cam);
            
            
            if or(app.EyesNumPts < 10 , app.MouthNumPts < 10)
            
                [VideoFrame] = app.DetectObjects(VideoFrame);
            else
                [VideoFrame] =app.TrackObjects(VideoFrame);
            end
            if strcmp(app.VerticalFlipSwitch.Value,'On')
                VideoFrame = flipud(VideoFrame);
            end
            % ビデオプレイヤーフレーム更新
            app.Image.ImageSource = VideoFrame;
            
        end
        %End mytimer_func------------------------------------------------------------------
        
        function [Return_VideoFrame] = DetectObjects(app,VideoFrame)
            disp('Detect')
            %グレースケール画像を生成
            GrayFrameImage = rgb2gray(VideoFrame);
            
            %顔領域をグレースケール画像で特定
            FaceBBox = app.FaceDetector.step(GrayFrameImage);
        
            %顔位置特定が成功したら
            if ~isempty(FaceBBox)
                
                %、顔領域の中から目の位置を特定
                EyesBBox = app.EyesDetector.step(GrayFrameImage,FaceBBox(1,:));
                %、顔領域の中から口の位置を特定
                MouthBBox = app.MouthDetector.step(GrayFrameImage,FaceBBox(1,:));
            
                %目の領域が特定できていたらVideoFrameに位置情報を重畳する。
                if ~isempty(EyesBBox)
                    % 特定領域のコーナ点を特定する.
                    Points = detectMinEigenFeatures(GrayFrameImage, 'ROI', EyesBBox(1, :));
                  
                    % ポイントトラッカーの初期化
                    xyPoints = Points.Location;
                    app.EyesNumPts = size(xyPoints,1);
                    release(app.EyesTracker);
                    initialize(app.EyesTracker, xyPoints, GrayFrameImage);
                    %EyeesPointの位置をメモリに記憶させる.
                    app.EyesOldPoints = xyPoints;
                    
                    app.EyesbboxPoints = bbox2points(EyesBBox(1, :));

                    % Convert the box corners into the [x1 y1 x2 y2 x3 y3 x4 y4]
                    % format required by insertShape.
                    bboxPolygon = reshape(app.EyesbboxPoints', 1, []);
                
                    % Display a bounding box around the detected face.
                    VideoFrame = insertShape(VideoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
                
                    % Display detected corners.
                    VideoFrame = insertMarker(VideoFrame, xyPoints, '+', 'Color', 'white');
                end
                
                
                %口の領域が特定できていたらVideoFrameに位置情報を重畳する。
                if ~isempty(MouthBBox)
                    % 特定領域のコーナ点を特定する.
                    Points = detectMinEigenFeatures(GrayFrameImage, 'ROI', MouthBBox(1, :));
                  
                    % ポイントトラッカーの初期化
                    xyPoints = Points.Location;
                    app.MouthNumPts = size(xyPoints,1);
                    release(app.MouthTracker);
                    initialize(app.MouthTracker, xyPoints, GrayFrameImage);
                    %EyeesPointの位置をメモリに記憶させる.
                    app.MouthOldPoints = xyPoints;
                    app.MouthbboxPoints = bbox2points(MouthBBox(1, :));

                    % Convert the box corners into the [x1 y1 x2 y2 x3 y3 x4 y4]
                    % format required by insertShape.
                    bboxPolygon = reshape(app.MouthbboxPoints', 1, []);
                
                    % Display a bounding box around the detected face.
                    VideoFrame = insertShape(VideoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
                
                    % Display detected corners.
                    VideoFrame = insertMarker(VideoFrame, xyPoints, '+', 'Color', 'white');
                end

            end
            Return_VideoFrame = VideoFrame;

        end
        %End DetectObjects------------------------------------------------------------------

        
        function Return_VideoFrame = TrackObjects(app,VideoFrame)
            
            GrayFrameImage = rgb2gray(VideoFrame);
            
            [xyPoints, isFound] = step(app.EyesTracker, GrayFrameImage);
            visiblePoints = xyPoints(isFound, :);
            oldInliers = app.EyesOldPoints(isFound, :);
            app.EyesNumPts = size(visiblePoints, 1);

           %目のトラックポイントが10以上なトラックを継続する。
           if app.EyesNumPts >= 10
                % 一つ前のフレームで作成したポイント位置
                %現在のフレームのポイント位置のズレから2次元の幾何学変換を推定
                [xform, inlierIdx] = estimateGeometricTransform2D(...
                    oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                visiblePoints = visiblePoints(inlierIdx, :);
    
                % 順方向アフィン変換でトラッキング領域を示すポリゴン座標を変換
                app.EyesbboxPoints = transformPointsForward(xform, app.EyesbboxPoints);
    
                % 特定した目領域を示す四角形の座標
                % 座標形式を[x1 y1 x2 y2 x3 y3 x4 y4]に変換
                
                bboxPolygon = reshape(app.EyesbboxPoints', 1, []);
    
                %平行四辺形にならないように調整
                bboxPolygon(1) = max(bboxPolygon(1),bboxPolygon(7));%X1
                bboxPolygon(2) = max(bboxPolygon(2),bboxPolygon(4));%Y1
                bboxPolygon(3) = max(bboxPolygon(3),bboxPolygon(5));%X2
                bboxPolygon(4) = max(bboxPolygon(2),bboxPolygon(4));%Y2
                bboxPolygon(5) = max(bboxPolygon(3),bboxPolygon(5));%X3
                bboxPolygon(6) = max(bboxPolygon(6),bboxPolygon(8));%Y3
                bboxPolygon(7) = max(bboxPolygon(1),bboxPolygon(7));%X4
                bboxPolygon(8) = max(bboxPolygon(6),bboxPolygon(8));%Y4
                
                Xmin  = round(max(bboxPolygon(1),bboxPolygon(7))) - app.XExpand ;
                Xmax = round(max(bboxPolygon(3),bboxPolygon(5))) + app.XExpand ;
                Ymin  = round(max(bboxPolygon(2),bboxPolygon(4))) - app.YExpand ;
                Ymax = round(max(bboxPolygon(6),bboxPolygon(8))) + app.YExpand ;
                
                % ポリゴンを画面に重畳.
                %VideoFrame = insertShape(VideoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
    
                % トラックポイントを描画.
                %videoFrame = insertMarker(videoFrame, visiblePoints, '+', 'Color', 'white');
    
                % ポイント位置を更新
                %EyeesPointの位置をメモリに記憶させる.
                app.EyesOldPoints =  visiblePoints;
                setPoints(app.EyesTracker,app.EyesOldPoints);
                
                %錯視が有効ならば目の位置のフレームを反転させる
                if strcmp(app.IllusionSwitch.Value,'On')
                    VideoFrame(Ymin:Ymax,Xmin:Xmax,:) = flipud(VideoFrame(Ymin:Ymax,Xmin:Xmax,:));
                end
           end
           

           [xyPoints, isFound] = step(app.MouthTracker, GrayFrameImage);
           visiblePoints = xyPoints(isFound, :);
           soldInliers = app.MouthOldPoints(isFound, :);
           app.MouthNumPts = size(visiblePoints, 1);
           
           %口のトラックポイントが10以上なトラックを継続する。
           if app.MouthNumPts >= 10
                % 一つ前のフレームで作成したポイント位置
                %現在のフレームのポイント位置のズレから2次元の幾何学変換を推定
                [xform, inlierIdx] = estimateGeometricTransform2D(...
                    soldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                visiblePoints = visiblePoints(inlierIdx, :);
    
                % 順方向アフィン変換でトラッキング領域を示すポリゴン座標を変換
                app.MouthbboxPoints = transformPointsForward(xform, app.MouthbboxPoints);
    
                % 特定した目領域を示す四角形の座標
                % 座標形式を[x1 y1 x2 y2 x3 y3 x4 y4]に変換
                
                bboxPolygon = reshape(app.MouthbboxPoints', 1, []);
    
                %平行四辺形にならないように調整
                bboxPolygon(1) = max(bboxPolygon(1),bboxPolygon(7));%X1
                bboxPolygon(2) = max(bboxPolygon(2),bboxPolygon(4));%Y1
                bboxPolygon(3) = max(bboxPolygon(3),bboxPolygon(5));%X2
                bboxPolygon(4) = max(bboxPolygon(2),bboxPolygon(4));%Y2
                bboxPolygon(5) = max(bboxPolygon(3),bboxPolygon(5));%X3
                bboxPolygon(6) = max(bboxPolygon(6),bboxPolygon(8));%Y3
                bboxPolygon(7) = max(bboxPolygon(1),bboxPolygon(7));%X4
                bboxPolygon(8) = max(bboxPolygon(6),bboxPolygon(8));%Y4
                
                Xmin  = round(max(bboxPolygon(1),bboxPolygon(7))) - app.XExpand ;
                Xmax = round(max(bboxPolygon(3),bboxPolygon(5))) + app.XExpand ;
                Ymin  = round(max(bboxPolygon(2),bboxPolygon(4))) - app.YExpand ;
                Ymax = round(max(bboxPolygon(6),bboxPolygon(8))) + app.YExpand ;
                
                % ポリゴンを画面に重畳.
                %VideoFrame = insertShape(VideoFrame, 'Polygon', bboxPolygon, 'LineWidth', 3);
    
                % トラックポイントを描画.
                %videoFrame = insertMarker(videoFrame, visiblePoints, '+', 'Color', 'white');
    
                % ポイント位置を更新
                %EyeesPointの位置をメモリに記憶させる.
                app.MouthOldPoints =  visiblePoints;
                setPoints(app.MouthTracker,app.MouthOldPoints);
                
                %錯視が有効ならば目の位置のフレームを反転させる
                if strcmp(app.IllusionSwitch.Value,'On')
                    VideoFrame(Ymin:Ymax,Xmin:Xmax,:) = flipud(VideoFrame(Ymin:Ymax,Xmin:Xmax,:));
                end
           end
           
           Return_VideoFrame = VideoFrame;
        end
        %End TrackObjects------------------------------------------------------------------
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %ウェブカメラリスト取得
            app.VideoSourceDropDown.Items = webcamlist;
            
            %カメラオブジェクト起動
            app.cam = webcam();

            %FPS設定
            FPS = 10;
         
            %プロパティ初期化
            app.runLoop = true;
            app.EyesNumPts = 0;
            app.MouthNumPts = 0;
            app.XExpand = 20;
            app.YExpand = 10;
            
            app.EnableIllusion = false;
            app.VerticalFlip = false;

            
            % 学習済み物体認識オブジェクトを生成
            app.FaceDetector = vision.CascadeObjectDetector();
            app.EyesDetector = vision.CascadeObjectDetector('EyePairSmall', 'UseROI', true);
            app.MouthDetector = vision.CascadeObjectDetector('Mouth', 'UseROI', true);
            
            %追跡オブジェクト生成
            app.EyesTracker = vision.PointTracker('MaxBidirectionalError', 2);
            app.MouthTracker = vision.PointTracker('MaxBidirectionalError', 2);

            %タイマオブジェクト生成
            app.UpdateTimer = timer('Period', 1/FPS,...
                'ExecutionMode', 'fixedRate', ...
                'TasksToExecute', Inf, ... 
                'TimerFcn', @app.mytimer_func);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.UpdateTimer);
            delete(app.UpdateTimer);
            delete(app.cam);
            delete(app)
            
        end

        % Value changed function: PlayVideoButton
        function PlayVideoButtonValueChanged(app, event)
            value = app.PlayVideoButton.Value;
            
            if value
                %タイマ開始
                start(app.UpdateTimer);
            else
                %タイマ停止
                stop(app.UpdateTimer);
            end
        end

        % Value changed function: VideoSourceDropDown
        function VideoSourceDropDownValueChanged(app, event)
            value = app.VideoSourceDropDown.Value;
            %カメラオブジェクト削除
            delete(app.cam);
            %カメラオブジェクト起動
            app.cam = webcam(value);

        end

        % Value changed function: IllusionSwitch
        function IllusionSwitchValueChanged(app, event)
            value = app.IllusionSwitch.Value;
            
        end

        % Value changed function: VerticalFlipSwitch
        function VerticalFlipSwitchValueChanged(app, event)
            value = app.VerticalFlipSwitch.Value;
            
        end

        % Button pushed function: ReDetectObjectsButton
        function ReDetectObjectsButtonPushed(app, event)
            app.EyesNumPts = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.VideoControlPanel = uipanel(app.UIFigure);
            app.VideoControlPanel.Title = 'Video Control';
            app.VideoSourceDropDownLabel = uilabel(app.VideoControlPanel);
            app.VideoSourceDropDownLabel.Text = 'VideoSource';
            app.VideoSourceDropDown = uidropdown(app.VideoControlPanel);
            app.VideoSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoSourceDropDownValueChanged, true);
            app.PlayVideoButton = uibutton(app.VideoControlPanel, 'state');
            app.PlayVideoButton.ValueChangedFcn = createCallbackFcn(app, @PlayVideoButtonValueChanged, true);
            app.PlayVideoButton.Text = 'Play Video';
            app.ReDetectObjectsButton = uibutton(app.VideoControlPanel, 'push');
            app.ReDetectObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ReDetectObjectsButtonPushed, true);
            app.ReDetectObjectsButton.Text = 'ReDetect Objects';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.IllusionStatusControlPanel = uipanel(app.UIFigure);
            app.IllusionStatusControlPanel.Title = 'Illusion Status Control';
            app.IllusionSwitchLabel = uilabel(app.IllusionStatusControlPanel);
            app.IllusionSwitchLabel.Text = 'Illusion';
            app.IllusionSwitch = uiswitch(app.IllusionStatusControlPanel, 'rocker');
            app.IllusionSwitch.ValueChangedFcn = createCallbackFcn(app, @IllusionSwitchValueChanged, true);
            app.VerticalFlipSwitchLabel = uilabel(app.IllusionStatusControlPanel);
            app.VerticalFlipSwitchLabel.Text = 'Vertical Flip';
            app.VerticalFlipSwitch = uiswitch(app.IllusionStatusControlPanel, 'rocker');
            app.VerticalFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @VerticalFlipSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThacherIllusionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end